classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        triaxialExperimentResults       matlab.ui.Figure
        tabGroup                        matlab.ui.container.TabGroup
        experimentsAndRockDataTab       matlab.ui.container.Tab
        experimentOverviewPanel         matlab.ui.container.Panel
        experimentsOverviewTable        matlab.ui.control.Table
        SelectedExperimentLabel         matlab.ui.control.Label
        selectExperimentPrimary         matlab.ui.control.DropDown
        rockDataPanel                   matlab.ui.container.Panel
        rockDataTable                   matlab.ui.control.Table
        dataAndOutliersTab              matlab.ui.container.Tab
        dataAndWeightPanel              matlab.ui.container.Panel
        graphOverview                   matlab.ui.control.UIAxes
        graphWeight                     matlab.ui.control.UIAxes
        displayOutliersWeight           matlab.ui.control.CheckBox
        correctedGraphWeight            matlab.ui.control.CheckBox
        fluidFlowAndDeformationPanel    matlab.ui.container.Panel
        graphFluidFlow                  matlab.ui.control.UIAxes
        graphDeformation                matlab.ui.control.UIAxes
        timelogTab                      matlab.ui.container.Tab
        permeabilityResultsPanel        matlab.ui.container.Panel
        graphTimelogTab                 matlab.ui.control.UIAxes
        DisplayonrightaxisLabel         matlab.ui.control.Label
        y2AxisTimelogTab                matlab.ui.control.DropDown
        clearEventsButton               matlab.ui.control.Button
        timelogPanel                    matlab.ui.container.Panel
        timelogTableActual              matlab.ui.control.Table
        timelogTablePre                 matlab.ui.control.Table
        timelogTablePost                matlab.ui.control.Table
        TimestepminLabel_2              matlab.ui.control.Label
        selectTimestepPrimary           matlab.ui.control.DropDown
        DisplayonleftaxisLabel          matlab.ui.control.Label
        y1AxisTimelogTab                matlab.ui.control.DropDown
        comparingResultsTab             matlab.ui.container.Tab
        comparePanel                    matlab.ui.container.Panel
        graphPrimaryComparisonTab       matlab.ui.control.UIAxes
        graphSecondaryComparisonTab     matlab.ui.control.UIAxes
        selectExperimentSecondary       matlab.ui.control.DropDown
        ComparetoExperimentNoLabel      matlab.ui.control.Label
        TimestepminLabel                matlab.ui.control.Label
        selectTimestepSecondary         matlab.ui.control.DropDown
        DisplayonrightaxisLabel_2       matlab.ui.control.Label
        y2AxisPrimaryComparisonTab      matlab.ui.control.DropDown
        DisplayonrightaxisLabel_3       matlab.ui.control.Label
        y2AxisSecondaryComparisonTab    matlab.ui.control.DropDown
        DisplayonleftaxisDropDownLabel  matlab.ui.control.Label
        y1AxisPrimaryComparisonTab      matlab.ui.control.DropDown
        DisplayonleftaxisDropDown_2Label  matlab.ui.control.Label
        y1AxisSecondaryComparisonTab    matlab.ui.control.DropDown
        rockDataComparePagePrimary      matlab.ui.control.Table
        rockDataComparePageSecondary    matlab.ui.control.Table
        compare2Tab                     matlab.ui.container.Tab
        graphPrimaryComparisonTab2      matlab.ui.control.UIAxes
        graphSecondaryComparisonTab2    matlab.ui.control.UIAxes
        LeftAxisDropDownLabel           matlab.ui.control.Label
        y1AxisPrimaryComparisonTab2     matlab.ui.control.DropDown
        xAxisDropDown_2Label            matlab.ui.control.Label
        xAxisPrimaryComparisonTab2      matlab.ui.control.DropDown
        RightAxisDropDownLabel          matlab.ui.control.Label
        y2AxisPrimaryComparisonTab2     matlab.ui.control.DropDown
        LeftAxisDropDown_2Label         matlab.ui.control.Label
        y1AxisSecondaryComparisonTab2   matlab.ui.control.DropDown
        xAxisDropDownLabel              matlab.ui.control.Label
        xAxisSecondaryComparisonTab2    matlab.ui.control.DropDown
        RightAxisDropDown_2Label        matlab.ui.control.Label
        y2AxisSecondaryComparisonTab2   matlab.ui.control.DropDown
    end

    
    properties (Access = public)
    %any variables that must be accessed throughout the App must be declared here    
       
         db % Database connection
         
         %variables for primary experiment loaded
         experimentNoPrimary
         dbSpecimenDataPrimary
         dbMetadataPrimary
         dbDataPrimary
         experimentsDataPrimary
         permeabilityDataPrimary
         permeabilityTimestepPrimary
         
         %variables for secondary experiment loaded
         experimentNoSecondary
         dbSpecimenDataSecondary
         dbMetaDataSecondary
         dbDataSecondary
         experimentsDataSecondary
         permeabilityDataSecondary
         permeabilityTimestepSecondary
         
         Analytics_timeStep %new Table (chosen timestep)
         flowMassOutliers %flowMass outliers graph
         plotCorrectedGraphWeight %corrected flowMass graph 
         Ylimit % y limits of permeability graph
         
         loadingProgBar % Progressbar while loading or calculating
         
         %variables for dropdown menus
         y1PlotTimelogTab
         y2PlotTimelogTab
         y1PlotPrimaryComparisonTab
         y2PlotPrimaryComparisonTab
         y1PlotSecondaryComparisonTab
         y2PlotSecondaryComparisonTab
         y1PlotPrimaryComparisonTab2
         y2PlotPrimaryComparisonTab2
         y1PlotSecondaryComparisonTab2
         y2PlotSecondaryComparisonTab2
         xPlotPrimaryComparisonTab2
         xPlotSecondaryComparisonTab2
         
         %variables containing the graphs
         strainSensorsMeanGraph
         fluidPressureGraph
         hydraulicCylinderGraph
         confiningPressureGraph
         flowMassGraph
         flowMassDiffGraph
         strainSensor1MeanDeltaGraph
         strainSensor2MeanDeltaGraph
         
         plotProperties
         
         timelog %timelog table
         %TimeStep %selected timestep
         
         %permeability_timeStep %permeability of selected timestep
         plotline %Line plot to mark timelog events
         plottext %number of timelog event

         %Analytics_compare %Data of compared experiment 

         %PermPlot %permeabiity plot
         %PlotPerm_timestep %permeability plot from new timestep
         eventRow
         
         %smoothPlot%smooth plot
         %smoothPlotTS%smooth plot from new timestep
         %ComparePlot%permeability plot for compare experiment
         %experiment_permeability %permeability for compare experiment
         %experiment_data %experiment data for compare experiment
         %experiment_specimendata% specimendata for compare experiment
         %experiment_num
    end
    
    methods (Access = private)

        function result = changeGraphDataSet(app, selectedDataset, selectedYValue, plotVar, graphVar, position, selectedXValue)
        %Function to plot graphs with given datasets into given graphs
        %depending on the handed over selected dataset (selected via
        %dropdown).
        
             try
                %The dataTable has to be selected depending on the dataset
                %(primary or secondary). And the two tables with
                %experimentsdatas (secondly) and permeabilitydata (e.g.
                %minutly) have to be synchronized.
                switch selectedDataset
                    case 'primary'                        
                        dataTable = synchronize(app.experimentsDataPrimary, app.permeabilityDataPrimary, 'first','linear');                                 
                    
                    case 'secondary'
                        dataTable = synchronize(app.experimentsDataSecondary, app.permeabilityDataSecondary, 'first','linear');
                        
                    otherwise
                        return
                end
                
                switch selectedXValue
                    case 'runtime'
                        xAxisLabel= 'Runtime [hh:mm]';
                        xAxisData=dataTable.runtime_1;
                    
                    case 'permeability'
                        xAxisLabel = 'Permeability in m/s';
                        xAxisData = dataTable.permeability; 
                        
                    case 'permeabilitySmooth'
                        xAxisLabel = 'Permeability (smooth) in m/s';
                        xAxisData = dataTable.permeabilitySmooth; 
                    
                    case 'strainSensorsMean'
                        xAxisLabel = 'Deformation in mm';
                        xAxisData = dataTable.strainSensorsMean;
                        
                    case 'hydrCylinderPressureRel'
                        xAxisLabel = 'Pressure of the Hydraulic Cylinder in Bar';
                        xAxisData = dataTable.hydrCylinderPressureRel;
                        
                    case 'fluidPressureRel'
                        xAxisLabel = 'Fluid Pressure in bar';
                        xAxisData = dataTable.fluidPressureRel;
                      
                    case 'confiningPressureRel'
                        xAxisLabel = 'Confining Pressure in bar';
                        xAxisData = dataTable.confiningPressureRel;
                        
                    case 'pump_sum'
                        xAxisLabel = 'Pump pressure in bar';
                        xAxisData = dataTable.pump_sum;
                    
                    case 'flowMass'
                        xAxisLabel = 'Fluid mass in kg';
                        xAxisData = dataTable.flowMass;
                        
                    case 'flowMassDiff'
                        xAxisLabel = 'Weight difference in kg';
                        xAxisData = dataTable.flowMassDiff_1;
                        
                    case 'fluidOutTemp'
                        xAxisLabel = 'Fluid Temperature in Degrees Celsius';
                        xAxisData = dataTable.fluidOutTemp;
                        
                    case 'roomTemp'
                        xAxisLabel = 'Room Temperature in Degrees Celsius';
                        xAxisData = dataTable.roomTemp;
                    
                    otherwise
                        xAxisData=dataTable.runtime;
                        xAxisLabel='Runtime [hh:mm]';
                end

%                 xAxisData = dataTable.runtime;
                
                %Yaxis label and data are depending on the selected database
                switch selectedYValue
                    case 'permeability'
                        yAxisLabel = 'Permeability in m/s';
                        yAxisData = dataTable.permeability; 
                        
                    case 'permeabilitySmooth'
                        yAxisLabel = 'Permeability (smooth) in m/s';
                        yAxisData = dataTable.permeabilitySmooth; 
                    
                    case 'strainSensorsMean'
                        yAxisLabel = 'Deformation in mm';
                        yAxisData = dataTable.strainSensorsMean;
                        
                    case 'hydrCylinderPressureRel'
                        yAxisLabel = 'Pressure of the Hydraulic Cylinder in Bar';
                        yAxisData = dataTable.hydrCylinderPressureRel;
                        
                    case 'fluidPressureRel'
                        yAxisLabel = 'Fluid Pressure in bar';
                        yAxisData = dataTable.fluidPressureRel;
                      
                    case 'confiningPressureRel'
                        yAxisLabel = 'Confining Pressure in bar';
                        yAxisData = dataTable.confiningPressureRel;
                        
                    case 'pump_sum'
                        yAxisLabel = 'Pump pressure in bar';
                        yAxisData = dataTable.pump_sum;
                    
                    case 'flowMass'
                        yAxisLabel = 'Weight in kg';
                        yAxisData = dataTable.flowMass;
                        
                    case 'flowMassDiff'
                        yAxisLabel = 'Weight difference in kg';
                        yAxisData = dataTable.flowMassDiff_1;
                        
                    case 'fluidOutTemp'
                        yAxisLabel = 'Fluid Temperature in Degrees Celsius';
                        yAxisData = dataTable.fluidOutTemp;
                        
                    case 'roomTemp'
                        yAxisLabel = 'Room Temperature in Degrees Celsius';
                        yAxisData = dataTable.roomTemp;
                    
                    case 'strainSensor1MeanDelta'
                        yAxisLabel = 'Deviation of Sensor 1 from the mean';
                        yAxisData = dataTable.strainSensor1MeanDelta;
                    
                    case 'strainSensor2MeanDelta'
                        yAxisLabel = 'Deviation of Sensor 2 from the mean';
                        yAxisData = dataTable.strainSensor2MeanDelta;
                        
                    otherwise
                        yAxisLabel = '';
                        yAxisData=dataTable.permeability;
                end
                
                %check if the dataset shall be printed on the left or the right side
                if ~strcmp(selectedYValue, 'none')
                    switch position
                        case 'left'
                            
                            yyaxis(graphVar, 'left');
                            %%still showing duration error
                           switch  selectedXValue  
                               case 'runtime'
                            plotVar = plot(graphVar, xAxisData, yAxisData,'DurationTickFormat', 'hh:mm','DisplayName',yAxisLabel);
                               otherwise
                            cla(graphVar,'reset');
                            plotVar = plot(graphVar, xAxisData, yAxisData,'DisplayName',yAxisLabel);
                            end 
                            
                            plotVar.Color = app.plotProperties.lineColor('left');
                            plotVar.LineStyle = app.plotProperties.lineStyle('left');
                            plotVar.Marker = app.plotProperties.marker('left');
                            ylabel(graphVar, yAxisLabel);
                            xlabel(graphVar, xAxisLabel);
                            graphVar.YColor = app.plotProperties.lineColor('left');
                            graphVar.BackgroundColor='white';
                            legend(graphVar,'-DynamicLegend');
                            hold (graphVar, 'on') 
                            
                        case 'right'
                            yyaxis(graphVar, 'right');
                            
                            switch  selectedXValue  
                               case 'runtime'
                            plotVar = plot(graphVar, xAxisData, yAxisData, 'DurationTickFormat', 'hh:mm','DisplayName',yAxisLabel);
                               otherwise
                            plotVar = plot(graphVar, xAxisData, yAxisData,'DisplayName',yAxisLabel);
                            end
                            
                            plotVar.Color = app.plotProperties.lineColor('right');
                            plotVar.LineStyle = app.plotProperties.lineStyle('right');
                            plotVar.Marker = app.plotProperties.marker('right');
                            ylabel(graphVar, yAxisLabel);
                            xlabel(graphVar, xAxisLabel);
                            graphVar.YColor = app.plotProperties.lineColor('right');
                            graphVar.BackgroundColor='white';
                            legend(graphVar, '-DynamicLegend');
                            hold (graphVar, 'on')
                    end   
                    
                    result = plotVar;
                 else
                    result = 0;
                 end
                
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
                result = 0;
                return;
             end
            
        end
        
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        %Function that is called after gui components are initialized. It
        %develops the connection to the database and loads a list of all
        %available experiments.
        %All dropdown menus will be initialized and filled with data,
        %especcialy the dropdown menu to choose an experiment.
        
        import MeridDB.*
            
            %Initialize property-table for plots/graphs
            app.plotProperties = table('Size', [2,3], 'VariableTypes', {'string', 'string', 'string'}, 'RowNames', {'left';'right'}, 'VariableNames',{'lineColor','marker','lineStyle'});
            app.plotProperties.lineColor('left') = '#0072BD';
            app.plotProperties.marker('left') = 'none';
            app.plotProperties.lineStyle('left') = '-';
            app.plotProperties.lineColor('right') = '#D95319';
            app.plotProperties.marker('right') = 'none';
            app.plotProperties.lineStyle('right') = '-.';
            
            %Initialize database
            app.db = MeridDB('hiwi_ro','#meridDB2019','134.130.87.47',3306);
            
            %Request a list of all experiments and print out in table
                %Request list of experiments
                experimentOverview = app.db.getExperiments;
                
                %Format datetime-variables
                experimentOverview.timeStart.Format = 'dd.MM.uu HH:mm';
                experimentOverview.timeEnd.Format = 'dd.MM.uu HH:mm';
                
                %Output
                app.experimentsOverviewTable.Data = experimentOverview;
                app.experimentsOverviewTable.ColumnName = {'#','Short','Begin','End'};
            
            
            %Initialization of dropdowns
                %Experiment-selection dropdown
                dropDownList_Text = ["- no selection -"; strcat(string(experimentOverview.experimentNo), " - ", string(experimentOverview.short))];
                dropDownList_Data = [0; experimentOverview.experimentNo];
                app.selectExperimentPrimary.Items = dropDownList_Text;
                app.selectExperimentPrimary.ItemsData = dropDownList_Data;
                app.selectExperimentPrimary.Tooltip='The permeability will be calculated using a default value of 5 minutes. To select a different timestep, use the Dropdown on the "Results" page.';
                app.xAxisPrimaryComparisonTab2.Tooltip='This dropdown responds to the data of the LEFT dropdown only.';
                app.xAxisSecondaryComparisonTab2.Tooltip='This dropdown responds to the data of the LEFT dropdown only.';
                
                app.selectExperimentSecondary.Items = app.selectExperimentPrimary.Items;
                app.selectExperimentSecondary.ItemsData = app.selectExperimentPrimary.ItemsData;
            
                %Permeability timestep-selection dropdown
                app.selectTimestepPrimary.Items = ['- no selection -'; string([1; 2; 5; 10; 15; 20; 25; 30; 45; 60])];
                app.selectTimestepPrimary.ItemsData = [0; 1; 2; 5; 10; 15; 20; 25; 30; 45; 60];
                app.selectTimestepSecondary.Items = app.selectTimestepPrimary.Items;
                app.selectTimestepSecondary.ItemsData = app.selectTimestepPrimary.ItemsData;
            
                %Dataset-selection dropdown for graphs
                dropDownList_Text = {'- no selection -', 'Permeability', 'Permeability (smoothed)', 'Strain', 'Hydraulic Cylinder', 'Fluid Pressure', 'Confining Pressure', 'Pump pressure', 'Weight Difference', 'Fluid Temperature', 'Room Temperature'};
                dropDownList_Data = {'none'; 'permeability'; 'permeabilitySmooth'; 'strainSensorsMean'; 'hydrCylinderPressureRel'; 'fluidPressureRel'; 'confiningPressureRel'; 'pump_sum'; 'flowMassDiff'; 'fluidOutTemp'; 'roomTemp'};
                dropDownList_Text_xAxis={'runtime', 'Permeability', 'Permeability (smoothed)', 'Strain', 'Hydraulic Cylinder', 'Fluid Pressure', 'Confining Pressure', 'Pump pressure', 'Weight Difference', 'Fluid Temperature', 'Room Temperature'};
                dropDownList_Data_xAxis = {'runtime'; 'permeability'; 'permeabilitySmooth'; 'strainSensorsMean'; 'hydrCylinderPressureRel'; 'fluidPressureRel'; 'confiningPressureRel'; 'pump_sum'; 'flowMassDiff'; 'fluidOutTemp'; 'roomTemp'};
                
                
                app.y2AxisPrimaryComparisonTab.Items = dropDownList_Text;
                app.y2AxisPrimaryComparisonTab.ItemsData = dropDownList_Data;
                app.y2AxisSecondaryComparisonTab.Items = dropDownList_Text;
                app.y2AxisSecondaryComparisonTab.ItemsData = dropDownList_Data;
                app.y2AxisTimelogTab.Items = dropDownList_Text;
                app.y2AxisTimelogTab.ItemsData = dropDownList_Data;
                app.y1AxisTimelogTab.Items = dropDownList_Text;
                app.y1AxisTimelogTab.ItemsData = dropDownList_Data;
                app.y1AxisPrimaryComparisonTab.Items = dropDownList_Text;
                app.y1AxisPrimaryComparisonTab.ItemsData = dropDownList_Data;
                app.y1AxisSecondaryComparisonTab.Items = dropDownList_Text;
                app.y1AxisSecondaryComparisonTab.ItemsData = dropDownList_Data;
                app.y1AxisPrimaryComparisonTab2.Items = dropDownList_Text;
                app.y1AxisPrimaryComparisonTab2.ItemsData = dropDownList_Data;
                app.y2AxisPrimaryComparisonTab2.Items = dropDownList_Text;
                app.y2AxisPrimaryComparisonTab2.ItemsData = dropDownList_Data;
                app.xAxisPrimaryComparisonTab2.Items = dropDownList_Text_xAxis;
                app.xAxisPrimaryComparisonTab2.ItemsData = dropDownList_Data_xAxis;
                app.y1AxisSecondaryComparisonTab2.Items = dropDownList_Text;
                app.y1AxisSecondaryComparisonTab2.ItemsData = dropDownList_Data;
                app.y2AxisSecondaryComparisonTab2.Items = dropDownList_Text;
                app.y2AxisSecondaryComparisonTab2.ItemsData = dropDownList_Data;
                app.xAxisSecondaryComparisonTab2.Items = dropDownList_Text_xAxis;
                app.xAxisSecondaryComparisonTab2.ItemsData = dropDownList_Data_xAxis;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %This part should be put into another function and should be
            %handeld by the tabs and ther children
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %panel visibility is off for all panels before calculation 
            app.rockDataPanel.Visible = 'off';
            app.dataAndWeightPanel.Visible = 'off';
            app.fluidFlowAndDeformationPanel.Visible = 'off';
            app.permeabilityResultsPanel.Visible = 'off';
            app.graphPrimaryComparisonTab.Visible = 'off';
            app.selectTimestepSecondary.Visible = 'off';
            app.comparePanel.Visible = 'off';
            app.y1AxisSecondaryComparisonTab.Visible = 'off';
            app.y2AxisSecondaryComparisonTab.Visible = 'off';
    
        end

        % Value changed function: displayOutliersWeight
        function displayOutliersWeightValueChanged(app, event)
        %Function that handels a checkbox change for displaying the
        %outliers in the flowMass on the scale.
        
            %display outliers for flowMass
            checkboxValue = app.displayOutliersWeight.Value;
            
            if checkboxValue == 1 
                TF = isoutlier(app.experimentsDataPrimary.flowMassDiff, 'mean');
                x = app.experimentsDataPrimary.runtime;
                y = app.experimentsDataPrimary.flowMassDiff;
                
                yyaxis(app.graphWeight, 'right')
                app.flowMassOutliers = plot(app.graphWeight, x(TF), y(TF), 'rx', 'HandleVisibility', 'off');
            
            else
                delete(app.flowMassOutliers)
                
            end
         
        end

        % Value changed function: correctedGraphWeight
        function correctedGraphWeightValueChanged(app, event)
        %Function that handels a checkbox change for displaying the
        %outliers in the flowMass on the scale.
        
            %display corrected flowMass curve     
            checkboxValue = event.Value;
                
            if checkboxValue == 1
                %delete current flowMass diff graph
                delete(app.flowMassDiffGraph)
                delete(app.flowMassOutliers)
                app.displayOutliersWeight.Value=0;
                x = app.permeabilityDataPrimary.runtime;
                y=app.permeabilityDataPrimary.flowMassDiff;
                yyaxis(app.graphWeight, 'right')
                app.plotCorrectedGraphWeight = plot(app.graphWeight, x, y, 'm-', 'HandleVisibility', 'off');
               
            else %delete plot if box is unchecked
                delete(app.plotCorrectedGraphWeight)
                %plot original flowMassDiff graph
                app.flowMassDiffGraph= app.changeGraphDataSet(...
                    'primary', ...
                    'flowMassDiff', ...
                    app.flowMassDiffGraph, ...
                    app.graphWeight, ...
                    'right','runtime');
                  
            end
        end

        % Value changed function: y2AxisTimelogTab
        function y2AxisTimelogTabValueChanged(app, event)
            
            dropdownSelection = event.Value;
            
            try
                delete(app.y2PlotTimelogTab);
            catch
            end
            
            try
                app.y2PlotTimelogTab = app.changeGraphDataSet( ...
                    'primary', ...
                    dropdownSelection, ...
                    app.y2PlotTimelogTab, ...
                    app.graphTimelogTab, ...
                    'right','runtime');    
            catch
            end
        
        end

        % Value changed function: selectTimestepPrimary
        function selectTimestepPrimaryValueChanged(app, event)
            
            %alert user if no timestep was selecetd
            if (app.selectTimestepPrimary.Value == 0)
                uialert(app.triaxialExperimentResults,'No timestep was selected.','Warning');
                return;
            end
            
            app.permeabilityTimestepPrimary = app.selectTimestepPrimary.Value;
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Show progress bar initialization
            titleText = strcat('Please Wait - Recalculating experiment #', string(app.experimentNoPrimary));
            messageText = 'Recalculating permeability';
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');  
            
            %get new tabe and permeability calculation 
            app.permeabilityDataPrimary = app.dbDataPrimary.getPermeability( ...
                app.dbSpecimenDataPrimary.height, ...
                app.dbSpecimenDataPrimary.diameter, ...
                app.permeabilityTimestepPrimary); 
            app.permeabilityDataPrimary.permeabilitySmooth = filloutliers(app.permeabilityDataPrimary.permeability, 'nearest', 'movmedian', 10000);
            
            app.y1AxisTimelogTab.Value = 'permeability';
            dropdownSelection = app.y1AxisTimelogTab.Value;
            
            try
                delete(app.y1PlotTimelogTab);
                app.y1PlotTimelogTab = app.changeGraphDataSet( ...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1PlotTimelogTab, ...
                    app.graphTimelogTab, ...
                    'left');
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
            app.y1AxisPrimaryComparisonTab.Value = 'permeability';
            dropdownSelection = app.y1AxisPrimaryComparisonTab.Value;
            
            try
                delete(app.y1PlotPrimaryComparisonTab);
                app.y1PlotPrimaryComparisonTab = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1PlotPrimaryComparisonTab, ...
                    app.graphPrimaryComparisonTab, ...
                    'left');  
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar);  
        end

        % Value changed function: selectExperimentPrimary
        function selectExperimentPrimaryValueChanged(app, event)
        
            %Reads the ItemsData not the ItemName
            %The value contains only the experiment number as numeric
            value = app.selectExperimentPrimary.Value;            
            
            %display alert if no experiment was selected
            if ~isnumeric(value(1)) %check if selection is numerical
                %Stop execution when no experiment was selected
                uialert(app.triaxialExperimentResults, 'No experiment was selected.', 'Error');
                return;
            end
            
            %Save selected experiment number as variable in app
            app.experimentNoPrimary = value; 
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Show progress bar initialization
            titleText = strcat('Please Wait - Loading experiment #', string(app.experimentNoPrimary));
            messageText = 'Loading the metadata';
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText);
            pause(0.3);
            app.loadingProgBar.Value=0.1; %Set progress of progressbar to 10% 
            
            %Clear graphs and tables and timelog
            cla(app.graphWeight,'reset')
            cla(app.graphDeformation,'reset')
            cla(app.graphFluidFlow,'reset')
            cla(app.graphOverview,'reset')
            cla(app.graphTimelogTab, 'reset')
            cla(app.graphPrimaryComparisonTab, 'reset')
            
            app.displayOutliersWeight.Value = 0;
            app.correctedGraphWeight.Value = 0;
            app.timelogTableActual.ColumnName = [];
            app.y2AxisTimelogTab.Value = 'none';
            app.y2AxisPrimaryComparisonTab.Value = 'none';
            
            %Try to load metadata and specimendata from database und show them in a table
            try
                %Get metadata from database
                app.dbMetadataPrimary = app.db.getMetaData((app.experimentNoPrimary));
                
                %Check if metadata was submitted, otherwise exit function
                if (app.dbMetadataPrimary == 0)
                    app.triaxialExperimentResults.Name = strcat('MERID Datainspector');
                    %Stop execution when no metadata was found
                    uialert(app.triaxialExperimentResults, 'No metadata for selected experiment found.', 'warning');
                    close(app.loadingProgBar);
                    return;
                    
                else
                    app.triaxialExperimentResults.Name = ['MERID Datainspector - Experiment: ', app.dbMetadataPrimary.short];
                    
                end
 
                %Get specimen and rock data from database
                app.dbSpecimenDataPrimary = app.db.getSpecimenData(app.experimentNoPrimary, app.dbMetadataPrimary.specimenId);
                
                %Check if specimen data was submitted, otherwise exit function
                if (app.dbSpecimenDataPrimary == 0)
                    %Stop execution when no metadata was found
                    uialert(app.triaxialExperimentResults, 'No specimendata for selected experiment found.', 'warning');
                    close(app.loadingProgBar);
                    return;
                end

            
                %Display rock data in Table
                rockdata = app.dbSpecimenDataPrimary.dataTable;
                rockdata.massSat = round((rockdata.massSat/1000),2);
                rockdata.massWet = round((rockdata.massWet/1000),2);
                rockdata.massDry = round((rockdata.massDry/1000),2);
                rockdata.height = round(rockdata.height,2);
                rockdata.diameter = round(rockdata.diameter,2);
                rockdata = rows2vars(rockdata);
                
                %Set table captions, extract units from table-units
                rockdata(:,1) = {'Specimen ID';'Specimen Name'; ...
                    strcat('Height [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('height'),']'); ...
                    strcat('Diameter [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('diameter'),']'); ...
                    strcat('Mass Saturated [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('massSat'),']'); ...
                    strcat('Mass Wet [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('massWet'),']'); ...
                    strcat('Mass Dry [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('massDry'),']'); ...
                    'Rock name'; ...
                    'Description'; ...
                    strcat('Density Wet [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('densityWet'),']'); ...
                    strcat('Density Saturated [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('densitySat'),']'); ...
                    strcat('Density Dry [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('densityDry'),']'); ...
                    strcat('Density Grain [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('densityGrain'),']'); ...
                    strcat('Permeability Coefficient [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('permCoeff'),']'); ...
                    'Porosity'; ...
                    'Void Ratio'; ...
                    strcat('Uniaxial Compression Strength [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('uniAxCompStrength'),']'); ...
                    strcat('Uniaxial Emodulus [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('uniAxEModulus'),']') ...
                    };
                
                %Write rockdata to table in GUI
                app.rockDataTable.RowName = []; %clear column property
                app.rockDataPanel.Title = ('Rock Data');
                app.rockDataTable.Data = rockdata;
                app.rockDataTable.ColumnName = {'Property','Value'};
                app.rockDataPanel.Visible = 'on';
                
                %Rock Data Compare Page
                ValueColumnPrimary=[app.dbSpecimenDataPrimary.rockDensityDry;...
                    round(app.dbSpecimenDataPrimary.rockDensityGrain,4);...
                    round(app.dbSpecimenDataPrimary.porosity,4);
                    app.dbSpecimenDataPrimary.uniAxialCompressiveStrength];
                PropertyColumnPrimary={'Density Dry'; 'Density Grain'; 'Porosity'; 'Uniaxial Compressive Strength'};
                
                rockDataTable=table(PropertyColumnPrimary,ValueColumnPrimary);
                rockDataTable(:,1) ={
                    strcat('Density Dry [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('densityDry'),']'); ...
                    strcat('Density Grain [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('densityGrain'),']'); ...
                    'Porosity'; ...
                    strcat('Uniaxial Compression Strength [',app.dbSpecimenDataPrimary.dataTable.Properties.VariableUnits('uniAxCompStrength'),']'); ...
                    };
                app.rockDataComparePagePrimary.Data=rockDataTable;
                
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading specimen- or metadata for the selected experiment.', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = ['Loading the experiments-data. ', 'This could take a couple of minutes!'];
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Value', 0.2);
            
            %Try to load experiment data from database
            try
                app.dbDataPrimary = app.db.getExperimentData(app.experimentNoPrimary); 
            
                %Load relevant experiments-data in local variable
                app.experimentsDataPrimary = app.dbDataPrimary.getAnalyticsDataForGUI();
                
            catch
                uialert(app.triaxialExperimentResults, 'No experiments-data for selected experiment found. Data import aborted. Please try again.', 'warning');
                %throw(E);
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = 'Calculating permeability';
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Value', 0.5);
            
            %Try to calculate permeability
            try            
                %Calculate permeability
                app.selectTimestepPrimary.Value = 5;
                app.permeabilityDataPrimary = app.dbDataPrimary.getPermeability(app.dbSpecimenDataPrimary.height, app.dbSpecimenDataPrimary.diameter); %Leaving the timestep-option out for default: 5 minutes
                app.permeabilityDataPrimary.permeabilitySmooth = filloutliers(app.permeabilityDataPrimary.permeability, 'nearest', 'movmedian', 10000);
                
            catch
                uialert(app.triaxialExperimentResults, 'Error while calculating permeability. Please try again.', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %THE FOLLOWING PART SHOULD BE IN ANOTHER FUNCTION THAT WILL BE
            %CALLES IN THIS PLACE!!!
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = 'Display all data';
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Value', 0.7);
            
                  
            %GRAPH OVERVIEW containing all relevant measurements
              
            app.changeGraphDataSet(...
                    'primary', ...
                    'strainSensorsMean', ...
                    app.strainSensorsMeanGraph, ...
                    app.graphOverview, ...
                    'left','runtime');
                   
            
             app.changeGraphDataSet(...
                    'primary', ...
                    'fluidPressureRel', ...
                    app.fluidPressureGraph, ...
                    app.graphOverview, ...
                    'right','runtime');
                
             app.changeGraphDataSet(...
                    'primary', ...
                    'hydrCylinderPressureRel', ...
                    app.hydraulicCylinderGraph, ...
                    app.graphOverview, ...
                    'right','runtime');
                                    
             app.changeGraphDataSet(...
                    'primary', ...
                    'confiningPressureRel', ...
                    app.confiningPressureGraph, ...
                    app.graphOverview, ...
                    'right','runtime');
                    
            title(app.graphOverview, 'Data Overview');

            %GRAPH FLUID FLOW containing fluid pressure
            
            app.changeGraphDataSet(...
                    'primary', ...
                    'fluidPressureRel', ...
                    app.fluidPressureGraph, ...
                    app.graphFluidFlow, ...
                    'left','runtime');
                    
            title(app.graphFluidFlow, 'Fluid Flow Data');
            
            %GRAPH WEIGHT containing flowMass and flowMass difference
                            
             app.changeGraphDataSet(...
                    'primary', ...
                    'flowMass', ...
                    app.flowMassGraph, ...
                    app.graphWeight, ...
                    'left','runtime');
            legend(app.graphWeight,'flowMass');     
             
             app.flowMassDiffGraph= app.changeGraphDataSet(...
                    'primary', ...
                    'flowMassDiff', ...
                    app.flowMassDiffGraph, ...
                    app.graphWeight, ...
                    'right','runtime');
                    
            title(app.graphWeight, 'Weigth and Weight Difference'); 
            
            %GRAPH DEFORMATION
            app.changeGraphDataSet(...
                    'primary', ...
                    'strainSensorsMean', ...
                    app.strainSensorsMeanGraph, ...
                    app.graphDeformation, ...
                    'left','runtime');
                    
            app.changeGraphDataSet(...
                    'primary', ...
                    'strainSensor1MeanDelta', ...
                    app.strainSensor1MeanDeltaGraph, ...
                    app.graphDeformation, ...
                    'right','runtime');
                    
            app.changeGraphDataSet(...
                    'primary', ...
                    'strainSensor2MeanDelta', ...
                    app.strainSensor2MeanDeltaGraph, ...
                    app.graphDeformation, ...
                    'right','runtime');
                    
            
            title(app.graphDeformation, 'Deformation and Deviation of Sensors');

            
            %make all panels visible
            app.dataAndOutliersTab.HandleVisibility='on';
            app.dataAndWeightPanel.Visible='on';
            app.fluidFlowAndDeformationPanel.Visible='on';
            app.permeabilityResultsPanel.Visible='on';
            app.comparePanel.Visible='on';
            
            %Plot y1-axis in graph on timetable tab
            %Plot Permeability
                app.y1AxisTimelogTab.Value = 'permeability';                
                dropdownSelection = app.y1AxisTimelogTab.Value;
            
                try
                    delete(app.y1PlotTimelogTab);
                    app.y1PlotTimelogTab = app.changeGraphDataSet( ...
                        'primary', ...
                        dropdownSelection, ...
                        app.y1PlotTimelogTab, ...
                        app.graphTimelogTab, ...
                        'left','runtime');
                        
                catch
                    
                end
    
                app.graphTimelogTab.Visible='on'; 

               
            %Plot y1-axis in primary graph on comparison tab
            %Plot Permeability
                app.y1AxisPrimaryComparisonTab.Value = 'permeability';
                dropdownSelection = app.y1AxisPrimaryComparisonTab.Value;
                
                try
                    delete(app.y1PlotPrimaryComparisonTab);
                    app.y1PlotPrimaryComparisonTab = app.changeGraphDataSet(...
                        'primary', ...
                        dropdownSelection, ...
                        app.y1PlotPrimaryComparisonTab, ...
                        app.graphPrimaryComparisonTab, ...
                        'left','runtime');
                           
                catch
                    uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
                end
    
                app.graphPrimaryComparisonTab.Visible='on'; 
            
            %set TIMELOG data in table 
                try
                    if isempty(app.dbMetadataPrimary.timeLog)
                        %empty data in tables
                        app.timelogTableActual.Data = [];
                        app.timelogTablePre.Data = [];
                        app.timelogTablePost.Data = [];
                        %add comment
                        app.timelogTableActual.ColumnName={'No timelog available'};
                        app.timelogTablePre.ColumnName = {'No timelog available'};
                        app.timelogTablePost.ColumnName = {'No timelog available'};
                        app.timelogTableActual.ColumnWidth = 'auto';
                        app.timelogTablePre.ColumnWidth = 'auto';
                        app.timelogTablePost.ColumnWidth = 'auto';
                        %remove events button
                        app.clearEventsButton.Visible = 'off';
                     
                    else 
                        app.clearEventsButton.Visible = 'on';
                        
                        %use any table that has not been retimed ... to find matching runtimes
                        %retime table as all timelog entries have format
                        %HH:MM
                        dataTableTimelog = retime(app.dbDataPrimary.getFlowData,'minutely','linear');
                        
                        %Only use timelog entries that match the start and
                        %end times of the data table-- otherwise runtime
                        %cannot be added as array size does not match
                        timerangeTable = timerange(dataTableTimelog.time(1), dataTableTimelog.time(end), 'closed');
                        app.timelog = table2timetable(app.dbMetadataPrimary.timeLog); %create timetable
                        app.timelog = app.timelog(timerangeTable,:);
                        app.timelog = timetable2table(app.timelog); %neccessary otherwise markers are not found
                        
                        %Find markers for time log events and add runtime to timelog
                        eventTime = app.timelog.time;
                        markers = dataTableTimelog(eventTime,:); %finds all rows of table corresponding to the datetime 
                        app.timelog.runtime = (markers.runtime); %add runtime to timelog
                        app.timelog.runtime.Format = ('hh:mm');
                        
                        %delete columns and sort table
                        app.timelog.logId = []; 
                        app.timelog.experimentNo = [];
                        app.timelog.retrospective = [];
                        app.timelog = sortrows(app.timelog, 'time', 'ascend'); %sort timelog table
                        app.timelog.time.Format = 'dd-MM-yyyy HH:mm';
                        app.timelog.selectEvents = false(size(app.timelog.description)); %add checkbox column
                        
                        %set row name, colum name and data in main UITable
                        app.timelogTableActual.Data = app.timelog;
                        app.timelogTableActual.ColumnName = {'Time','Description','Runtime','Select Events'};
                        app.timelogTableActual.RowName = 'numbered';
                       
                %FIND entries outside of experiment timerange
                timelogRemain = app.dbMetadataPrimary.timeLog;     
               
                     
                if app.dbMetadataPrimary.timeStart>timelogRemain.time(1) %all entries before start time
                              
                          start_of_entries=timelogRemain.time(1);
                          TR1=timerange(start_of_entries,timelogRemain.time(1),'closed'); %half open intervall
                          
                          timelogPre=table2timetable(timelogRemain);
                          timelogPre=timelogPre(TR1,:); %find all rows before start
                          timelogPre=timetable2table(timelogPre); %data for UITable must be table
                          timelogPre.logId=[];
                          timelogPre.experimentNo=[];
                          timelogPre.retrospective=[];
                          
                          %add 'not in range' to runtime and select events column
                          none=cell(size(timelogPre.time));
                          none{1,1}='not in range';
                          timelogPre.runtime=none;
                          timelogPre.selectEvents=none;
                          
                else
                     
                          col1={'No entries before start of the experiment'};
                          timelogPre=table(col1);
                          app.timelogTablePre.ColumnWidth='auto';
                          app.timelogTablePre.ColumnName={'No Timelog'};
                end
                
                if app.dbMetadataPrimary.timeEnd<timelogRemain.time(end) %all entries after end of experiment
                           TR2=timerange(app.dbMetadataPrimary.timeEnd,timelogRemain.time(end),'closed');  
                          
                          timelogPost=table2timetable(timelogRemain);
                          timelogPost=timelogPost(TR2,:); %find all rows before start
                          timelogPost=timetable2table(timelogPost); %data for UITable must be table
                          timelogPost.logId=[];
                          timelogPost.experimentNo=[];
                          timelogPost.retrospective=[];
                          
                          %add 'not in range' to runtime and select events column
                          none=cell(size(timelogPost.time));
                          none{1,1}='not in range';
                          timelogPost.runtime=none;
                          timelogPost.selectEvents=none;
                else 
                          col1={'No entries after end of the experiment'};
                          timelogPost=table(col1);
                          app.timelogTablePre.ColumnWidth='auto';
                          app.timelogTablePre.ColumnName={'No Timelog'};
                end 
                       
                          app.timelogTablePre.RowName='numbered';
                          app.timelogTablePre.ColumnName={'Time','Description','Runtime','Select Events'};
                          app.timelogTablePost.RowName='numbered';
                          app.timelogTablePost.ColumnName={'Time','Description','Runtime','Select Events'};


                        % set data in pre and post timelog tables
                        app.timelogTablePre.Data=timelogPre;
                        app.timelogTablePost.Data=timelogPost;
                     
                    end
                catch
                    uialert(app.triaxialExperimentResults, 'An error occured handling the timelog. No timelog for this experiment will be shown.', 'Warning');
                end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar); 

        end

        % Value changed function: selectExperimentSecondary
        function selectExperimentSecondaryValueChanged(app, event)
            
            %Reads the ItemsData not the ItemName
            %The value contains only the experiment number as numeric
            value = app.selectExperimentSecondary.Value;            
            
            %display alert if no experiment was selected
            if ~isnumeric(value(1)) %check if selection is numerical
                %Stop execution when no experiment was selected
                uialert(app.triaxialExperimentResults, 'No experiment was selected.', 'Error');
                return;
            end
            
            %Save selected experiment number as variable in app
            app.experimentNoSecondary = value;
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            titleText = strcat('Please Wait - Loading experiment #', string(app.experimentNoSecondary));
            messageText = 'Loading the metadata';
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText);
            pause(0.3);
            app.loadingProgBar.Value=0.1; %Set progress of progressbar to 10% 
            
            try
                %Get metadata from database
                app.dbMetaDataSecondary = app.db.getMetaData((app.experimentNoSecondary));
                
                %Check if metadata was submitted, otherwise exit function
                if (app.dbMetaDataSecondary == 0)
                    %Stop execution when no metadata was found
                    uialert(app.triaxialExperimentResults, 'No metadata for selected experiment found.', 'warning');
                    close(app.loadingProgBar);
                    return;
                end
 
                %Get specimen and rock data from database
                app.dbSpecimenDataSecondary = app.db.getSpecimenData(app.experimentNoSecondary, app.dbMetaDataSecondary.specimenId);
                
                %Check if specimen data was submitted, otherwise exit function
                if (app.dbSpecimenDataSecondary == 0)
                    %Stop execution when no metadata was found
                    uialert(app.triaxialExperimentResults, 'No specimendata for selected experiment found.', 'warning');
                    close(app.loadingProgBar);
                    return;
                end

            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading specimen- oder metadata for the selected experiment.', 'warning');
                close(app.loadingProgBar);
                return;
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = ['Loading the experiments-data. ', 'This could take a couple of minutes!'];
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Value', 0.2);
            
            %Try to load experiment data from database
            try
                app.dbDataSecondary = app.db.getExperimentData(app.experimentNoSecondary); 
            
                %Load relevant experiments-data in local variable
                app.experimentsDataSecondary = app.dbDataSecondary.getAnalyticsDataForGUI();
                
            catch
                uialert(app.triaxialExperimentResults, 'No experiments-data for selected experiment found. Data import aborted. Please try again.', 'warning');
                %throw(E);
                close(app.loadingProgBar);
                return;
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Rock Data Compare Page
                ValueColumn=[app.dbSpecimenDataSecondary.rockDensityDry;...
                    app.dbSpecimenDataSecondary.rockDensityGrain;...
                    app.dbSpecimenDataSecondary.porosity;
                    app.dbSpecimenDataSecondary.uniAxialCompressiveStrength];
                PropertyColumn={'Density Dry'; 'Density Grain'; 'Porosity'; 'Uniaxial Compressive Strength'};
                
                rockDataTable=table(PropertyColumn,ValueColumn);
                rockDataTable(:,1) ={
                    strcat('Density Dry [',app.dbSpecimenDataSecondary.dataTable.Properties.VariableUnits('densityDry'),']'); ...
                    strcat('Density Grain [',app.dbSpecimenDataSecondary.dataTable.Properties.VariableUnits('densityGrain'),']'); ...
                    'Porosity'; ...
                    strcat('Uniaxial Compression Strength [',app.dbSpecimenDataSecondary.dataTable.Properties.VariableUnits('uniAxCompStrength'),']'); ...
                    };
                app.rockDataComparePageSecondary.Data=rockDataTable;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar update
            messageText = 'Calculating permeability';
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Value', 0.5);
            
            %Try to calculate permeability
            try            
                %Calculate permeability
                app.selectTimestepSecondary.Value = 5;
                app.permeabilityDataSecondary = app.dbDataSecondary.getPermeability(app.dbSpecimenDataSecondary.height, app.dbSpecimenDataSecondary.diameter); %Leaving the timestep-option out for default: 5 minutes
                
            catch
                uialert(app.triaxialExperimentResults, 'Error while calculating permeability. Please try again.', 'warning');
                close(app.loadingProgBar);
                return;
            end
             
            %display permeability
            try
                delete(app.y1PlotSecondaryComparisonTab);
                app.y1PlotSecondaryComparisonTab = app.changeGraphDataSet(...
                    'secondary', ...
                    'permeability', ...
                    app.y1PlotSecondaryComparisonTab, ...
                    app.graphSecondaryComparisonTab, ...
                    'left','runtime'); 
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
          
            
            %Show timestep selection after data sucesfully loaded            
            app.selectTimestepSecondary.Visible = 'on';
            app.y1AxisSecondaryComparisonTab.Visible = 'on';
            app.y2AxisSecondaryComparisonTab.Visible = 'on';
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar);
        end

        % Value changed function: selectTimestepSecondary
        function selectTimestepSecondaryValueChanged(app, event)
            
            %alert user if no timestep was selecetd
            if (app.selectTimestepSecondary.Value == 0 || ~isnumeric(app.selectTimestepSecondary.Value))
                uialert(app.triaxialExperimentResults,'No timestep was selected.','Warning');
                return;
            end
            
            app.permeabilityTimestepSecondary = app.selectTimestepSecondary.Value;
                
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Show progress bar initialization
            titleText = strcat('Please Wait - Recalculating experiment #', string(app.experimentNoSecondary));
            messageText = 'Recalculating permeability';
            app.loadingProgBar = uiprogressdlg(app.triaxialExperimentResults, 'Title', titleText, 'Message', messageText, 'Indeterminate','on');  
            
            %get new tabe and permeability calculation 
            app.permeabilityDataSecondary = app.dbDataSecondary.getPermeability( ...
                app.dbSpecimenDataSecondary.height, ...
                app.dbSpecimenDataSecondary.diameter, ...
                app.permeabilityTimestepSecondary); 
            
            app.y1AxisSecondaryComparisonTab.Value = 'permeability';
            dropdownSelection = app.y1AxisSecondaryComparisonTab.Value;
            
            try
                delete(app.y1PlotSecondaryComparisonTab);
                app.y1PlotSecondaryComparisonTab = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y1PlotSecondaryComparisonTab, ...
                    app.graphSecondaryComparisonTab, ...
                    'left','runtime'); 
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %Progress bar closing
            close(app.loadingProgBar);  
        end

        % Cell edit callback: timelogTableActual
        function timelogTableActualCellEdit2(app, event)
            indices = event.Indices;   %row and column numer of event    
             
            ylimit=app.graphTimelogTab.YLim; %needed to place number in plot   
            app.eventRow=indices(1);
            
            isChecked=app.timelogTableActual.Data{app.eventRow,indices(2)}; %finds cheked boxes
            
            if isChecked==1
                RuntimeEvent=app.timelog(app.eventRow,3); %find runtime
                RuntimeEvent=table2array(RuntimeEvent);
                %plot vertical line
                app.plotline(app.eventRow)=plot(app.graphTimelogTab, [RuntimeEvent RuntimeEvent], [ylimit(1,1) ylimit(1,2)], 'm-', 'HandleVisibility', 'off');
                %plot row number
                nums=num2str(app.eventRow); 
                v=ones(size(RuntimeEvent))*(ylimit(1,2)*0.8); %location of number y axis
                app.plottext(app.eventRow)=text(app.graphTimelogTab, RuntimeEvent,v,nums);
                
            
            elseif isChecked==0
                delete(app.plotline(app.eventRow))
                delete(app.plottext(app.eventRow))
            
            end 
        end

        % Value changed function: y2AxisSecondaryComparisonTab
        function y2AxisSecondaryComparisonTabValueChanged(app, event)

            dropdownSelection = event.Value;

            try
                delete(app.y2PlotSecondaryComparisonTab);
            catch
            end
            
            try
                app.y2PlotSecondaryComparisonTab = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y2PlotSecondaryComparisonTab, ...
                    app.graphSecondaryComparisonTab, ...
                    'right','runtime');   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
        end

        % Value changed function: y2AxisPrimaryComparisonTab
        function y2AxisPrimaryComparisonTabValueChanged(app, event)
            dropdownSelection = event.Value;
            
            try
                delete(app.y2PlotPrimaryComparisonTab);
            catch
            end
            
            try
                app.y2PlotPrimaryComparisonTab = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y2PlotPrimaryComparisonTab, ...
                    app.graphPrimaryComparisonTab, ...
                    'right','runtime');
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end

        end

        % Button pushed function: clearEventsButton
        function clearEventsButtonPushed(app, event)
            cla(app.graphTimelogTab,'reset') %clear graph
            tf=isempty(app.dbMetadataPrimary.timeLog); %check if timelog exists
           
            
            %uncheck select events column
             if tf==0 
                app.timelogTableActual.Data{:,4}=0;
                
             end 
           try
             app.y1PlotTimelogTab=app.changeGraphDataSet(...
                    'primary', ...
                    app.y1AxisTimelogTab.Value, ...
                    app.y1PlotTimelogTab, ...
                    app.graphTimelogTab, ...
                    'left','runtime');
              
              app.y2PlotTimelogTab=app.changeGraphDataSet(...
                    'primary', ...
                    app.y2AxisTimelogTab.Value, ...
                    app.y2PlotTimelogTab, ...
                    app.graphTimelogTab, ...
                    'right','runtime');
                
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end 


        end

        % Value changed function: y1AxisTimelogTab
        function y1AxisTimelogTabValueChanged(app, event)
            
            dropdownSelection = event.Value;
            
            try
                delete(app.y1PlotTimelogTab);
            catch
            end
            
            try
                app.y1PlotTimelogTab = app.changeGraphDataSet( ...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1PlotTimelogTab, ...
                    app.graphTimelogTab, ...
                    'left','runtime');
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
        
        end

        % Value changed function: y1AxisPrimaryComparisonTab
        function y1AxisPrimaryComparisonTabValueChanged(app, event)
           
            dropdownSelection = event.Value;
            
            try
                delete(app.y1PlotPrimaryComparisonTab);
            catch
            end
            
            try
                app.y1PlotPrimaryComparisonTab = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1PlotPrimaryComparisonTab, ...
                    app.graphPrimaryComparisonTab, ...
                    'left','runtime');   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
  
        end

        % Value changed function: y1AxisSecondaryComparisonTab
        function y1AxisSecondaryComparisonTabValueChanged(app, event)
            dropdownSelection = event.Value;
            
            try
                delete(app.y1PlotSecondaryComparisonTab);
            catch
            end
            
            try
                app.y1PlotSecondaryComparisonTab = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y1PlotSecondaryComparisonTab, ...
                    app.graphSecondaryComparisonTab, ...
                    'left','runtime');   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end

        end

        % Value changed function: y1AxisPrimaryComparisonTab2
        function y1AxisPrimaryComparisonTab2ValueChanged(app, event)
    dropdownSelection = event.Value;
            
            try
                delete(app.y1PlotPrimaryComparisonTab2);
            catch
            end
            
            try
                app.y1PlotPrimaryComparisonTab2 = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y1PlotPrimaryComparisonTab2, ...
                    app.graphPrimaryComparisonTab2, ...
                    'left',app.xAxisPrimaryComparisonTab2.Value);   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
        end

        % Value changed function: y2AxisPrimaryComparisonTab2
        function y2AxisPrimaryComparisonTab2ValueChanged(app, event)
   dropdownSelection = event.Value;
            
            try
                delete(app.y2PlotPrimaryComparisonTab2);
            catch
            end
            
            try
                app.y2PlotPrimaryComparisonTab2 = app.changeGraphDataSet(...
                    'primary', ...
                    dropdownSelection, ...
                    app.y2PlotPrimaryComparisonTab2, ...
                    app.graphPrimaryComparisonTab2, ...
                    'right',app.xAxisPrimaryComparisonTab2.Value);   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
        end

        % Value changed function: y1AxisSecondaryComparisonTab2
        function y1AxisSecondaryComparisonTab2ValueChanged(app, event)
             dropdownSelection = event.Value;
            
            try
                delete(app.y1PlotSecondaryComparisonTab2);
            catch
            end
            
            try
                app.y1PlotSecondaryComparisonTab2 = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y1PlotSecondaryComparisonTab2, ...
                    app.graphSecondaryComparisonTab2, ...
                    'left',app.xAxisSecondaryComparisonTab2.Value);   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end
            
        end

        % Value changed function: y2AxisSecondaryComparisonTab2
        function y2AxisSecondaryComparisonTab2ValueChanged(app, event)
        dropdownSelection = event.Value;

            try
                delete(app.y2PlotSecondaryComparisonTab2);
            catch
            end
            
            try
                app.y2PlotSecondaryComparisonTab2 = app.changeGraphDataSet(...
                    'secondary', ...
                    dropdownSelection, ...
                    app.y2PlotSecondaryComparisonTab2, ...
                    app.graphSecondaryComparisonTab2, ...
                    'right',app.xAxisSecondaryComparisonTab2.Value);   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end 
        end

        % Value changed function: xAxisPrimaryComparisonTab2
        function xAxisPrimaryComparisonTab2ValueChanged(app, event)
  
        dropdownSelection = event.Value;

            try
                delete(app.xPlotPrimaryComparisonTab2);
            catch
            end
            
            try
                app.xPlotPrimaryComparisonTab2 = app.changeGraphDataSet(...
                    'primary', ...
                    app.y1AxisPrimaryComparisonTab2.Value, ...
                    app.xPlotPrimaryComparisonTab2, ...
                    app.graphPrimaryComparisonTab2, ...
                    'left',dropdownSelection);   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end 
            
      app.y2AxisPrimaryComparisonTab2.Value='none';      
            
        end

        % Value changed function: xAxisSecondaryComparisonTab2
        function xAxisSecondaryComparisonTab2ValueChanged(app, event)
           dropdownSelection = event.Value;

            try
                delete(app.xPlotSecondaryComparisonTab2);
            catch
            end
            
            try
                app.xPlotSecondaryComparisonTab2 = app.changeGraphDataSet(...
                    'secondary', ...
                    app.y1AxisSecondaryComparisonTab2.Value, ...
                    app.xPlotSecondaryComparisonTab2, ...
                    app.graphSecondaryComparisonTab2, ...
                    'left',dropdownSelection);   
            catch
                uialert(app.triaxialExperimentResults, 'An error occured while loading a new dataset for the selected graph.', 'warning');
            end 
            
            app.y2AxisSecondaryComparisonTab2.Value='none';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.triaxialExperimentResults = uifigure('Visible', 'off');
            app.triaxialExperimentResults.Name = 'MERID Datainspector';
            app.tabGroup = uitabgroup(app.triaxialExperimentResults);
            app.experimentsAndRockDataTab = uitab(app.tabGroup);
            app.experimentsAndRockDataTab.Title = 'Experiments and Rock Data';
            app.experimentOverviewPanel = uipanel(app.experimentsAndRockDataTab);
            app.experimentOverviewPanel.Title = 'Experiment Overview';
            app.experimentsOverviewTable = uitable(app.experimentOverviewPanel);
            app.experimentsOverviewTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.experimentsOverviewTable.RowName = {};
            app.experimentsOverviewTable.ColumnSortable = true;
            app.experimentsOverviewTable.ColumnEditable = false;
            app.SelectedExperimentLabel = uilabel(app.experimentOverviewPanel);
            app.SelectedExperimentLabel.Text = 'Selected Experiment:';
            app.selectExperimentPrimary = uidropdown(app.experimentOverviewPanel);
            app.selectExperimentPrimary.Items = {'- no selection -'};
            app.selectExperimentPrimary.ItemsData = {'0'};
            app.selectExperimentPrimary.ValueChangedFcn = createCallbackFcn(app, @selectExperimentPrimaryValueChanged, true);
            app.selectExperimentPrimary.Value = '0';
            app.rockDataPanel = uipanel(app.experimentsAndRockDataTab);
            app.rockDataPanel.Title = 'Rock Data';
            app.rockDataTable = uitable(app.rockDataPanel);
            app.rockDataTable.ColumnName = {'Value'};
            app.rockDataTable.RowName = {''};
            app.rockDataTable.ColumnSortable = false;
            app.dataAndOutliersTab = uitab(app.tabGroup);
            app.dataAndOutliersTab.Title = 'Data and Outliers';
            app.dataAndWeightPanel = uipanel(app.dataAndOutliersTab);
            app.dataAndWeightPanel.Title = 'All Data and Weight';
            app.graphOverview = uiaxes(app.dataAndWeightPanel);
            title(app.graphOverview, 'Title')
            xlabel(app.graphOverview, 'X')
            ylabel(app.graphOverview, 'Y')
            app.graphWeight = uiaxes(app.dataAndWeightPanel);
            title(app.graphWeight, 'Title')
            xlabel(app.graphWeight, 'X')
            ylabel(app.graphWeight, 'Y')
            app.displayOutliersWeight = uicheckbox(app.dataAndWeightPanel);
            app.displayOutliersWeight.ValueChangedFcn = createCallbackFcn(app, @displayOutliersWeightValueChanged, true);
            app.displayOutliersWeight.Text = 'Display outliers';
            app.correctedGraphWeight = uicheckbox(app.dataAndWeightPanel);
            app.correctedGraphWeight.ValueChangedFcn = createCallbackFcn(app, @correctedGraphWeightValueChanged, true);
            app.correctedGraphWeight.Text = 'Display corrected graph';
            app.fluidFlowAndDeformationPanel = uipanel(app.dataAndOutliersTab);
            app.fluidFlowAndDeformationPanel.Title = 'Fluid Pressure and deformation';
            app.graphFluidFlow = uiaxes(app.fluidFlowAndDeformationPanel);
            title(app.graphFluidFlow, 'Title')
            xlabel(app.graphFluidFlow, 'X')
            ylabel(app.graphFluidFlow, 'Y')
            app.graphDeformation = uiaxes(app.fluidFlowAndDeformationPanel);
            title(app.graphDeformation, 'Title')
            xlabel(app.graphDeformation, 'X')
            ylabel(app.graphDeformation, 'Y')
            app.timelogTab = uitab(app.tabGroup);
            app.timelogTab.Title = 'Results';
            app.permeabilityResultsPanel = uipanel(app.timelogTab);
            app.permeabilityResultsPanel.Title = 'Permeability results';
            app.graphTimelogTab = uiaxes(app.permeabilityResultsPanel);
            title(app.graphTimelogTab, 'Title')
            xlabel(app.graphTimelogTab, {'Runtime (hh:mm)'; ''})
            ylabel(app.graphTimelogTab, {'Permeability'; ''})
            app.DisplayonrightaxisLabel = uilabel(app.permeabilityResultsPanel);
            app.DisplayonrightaxisLabel.Text = 'Display on right axis:';
            app.y2AxisTimelogTab = uidropdown(app.permeabilityResultsPanel);
            app.y2AxisTimelogTab.Items = {'- no selection -'};
            app.y2AxisTimelogTab.ItemsData = {'0'};
            app.y2AxisTimelogTab.ValueChangedFcn = createCallbackFcn(app, @y2AxisTimelogTabValueChanged, true);
            app.y2AxisTimelogTab.Value = '0';
            app.clearEventsButton = uibutton(app.permeabilityResultsPanel, 'push');
            app.clearEventsButton.ButtonPushedFcn = createCallbackFcn(app, @clearEventsButtonPushed, true);
            app.clearEventsButton.Text = 'Clear marked events';
            app.timelogPanel = uipanel(app.permeabilityResultsPanel);
            app.timelogPanel.Title = 'Timelog';
            app.timelogTableActual = uitable(app.timelogPanel);
            app.timelogTableActual.ColumnName = {'column 1'};
            app.timelogTableActual.RowName = {};
            app.timelogTableActual.ColumnEditable = [false false false true];
            app.timelogTableActual.CellEditCallback = createCallbackFcn(app, @timelogTableActualCellEdit2, true);
            app.timelogTablePre = uitable(app.timelogPanel);
            app.timelogTablePre.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.timelogTablePre.RowName = {};
            app.timelogTablePost = uitable(app.timelogPanel);
            app.timelogTablePost.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.timelogTablePost.RowName = {};
            app.TimestepminLabel_2 = uilabel(app.permeabilityResultsPanel);
            app.TimestepminLabel_2.Text = 'Timestep (min):';
            app.selectTimestepPrimary = uidropdown(app.permeabilityResultsPanel);
            app.selectTimestepPrimary.Items = {'- no selection -'};
            app.selectTimestepPrimary.ItemsData = {'0'};
            app.selectTimestepPrimary.ValueChangedFcn = createCallbackFcn(app, @selectTimestepPrimaryValueChanged, true);
            app.selectTimestepPrimary.Value = '0';
            app.DisplayonleftaxisLabel = uilabel(app.permeabilityResultsPanel);
            app.DisplayonleftaxisLabel.Text = 'Display on left axis:';
            app.y1AxisTimelogTab = uidropdown(app.permeabilityResultsPanel);
            app.y1AxisTimelogTab.Items = {'- no selection -'};
            app.y1AxisTimelogTab.ItemsData = {'0'};
            app.y1AxisTimelogTab.ValueChangedFcn = createCallbackFcn(app, @y1AxisTimelogTabValueChanged, true);
            app.y1AxisTimelogTab.Value = '0';
            app.comparingResultsTab = uitab(app.tabGroup);
            app.comparingResultsTab.Title = 'Comparing Results';
            app.comparePanel = uipanel(app.comparingResultsTab);
            app.graphPrimaryComparisonTab = uiaxes(app.comparePanel);
            title(app.graphPrimaryComparisonTab, 'Title')
            xlabel(app.graphPrimaryComparisonTab, 'X')
            ylabel(app.graphPrimaryComparisonTab, 'Y')
            app.graphSecondaryComparisonTab = uiaxes(app.comparePanel);
            title(app.graphSecondaryComparisonTab, 'Title')
            xlabel(app.graphSecondaryComparisonTab, 'X')
            ylabel(app.graphSecondaryComparisonTab, 'Y')
            app.selectExperimentSecondary = uidropdown(app.comparePanel);
            app.selectExperimentSecondary.Items = {'- no selection -'};
            app.selectExperimentSecondary.ItemsData = {'0'};
            app.selectExperimentSecondary.ValueChangedFcn = createCallbackFcn(app, @selectExperimentSecondaryValueChanged, true);
            app.selectExperimentSecondary.Value = '0';
            app.ComparetoExperimentNoLabel = uilabel(app.comparePanel);
            app.ComparetoExperimentNoLabel.Text = 'Compare to Experiment No:';
            app.TimestepminLabel = uilabel(app.comparePanel);
            app.TimestepminLabel.Text = 'Timestep (min):';
            app.selectTimestepSecondary = uidropdown(app.comparePanel);
            app.selectTimestepSecondary.Items = {'- no selection -'};
            app.selectTimestepSecondary.ItemsData = {'0'};
            app.selectTimestepSecondary.ValueChangedFcn = createCallbackFcn(app, @selectTimestepSecondaryValueChanged, true);
            app.selectTimestepSecondary.Value = '0';
            app.DisplayonrightaxisLabel_2 = uilabel(app.comparePanel);
            app.DisplayonrightaxisLabel_2.Text = 'Display on right axis';
            app.y2AxisPrimaryComparisonTab = uidropdown(app.comparePanel);
            app.y2AxisPrimaryComparisonTab.Items = {'- no selection -'};
            app.y2AxisPrimaryComparisonTab.ItemsData = {'0'};
            app.y2AxisPrimaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @y2AxisPrimaryComparisonTabValueChanged, true);
            app.y2AxisPrimaryComparisonTab.Value = '0';
            app.DisplayonrightaxisLabel_3 = uilabel(app.comparePanel);
            app.DisplayonrightaxisLabel_3.Text = 'Display on right axis';
            app.y2AxisSecondaryComparisonTab = uidropdown(app.comparePanel);
            app.y2AxisSecondaryComparisonTab.Items = {'- no selection -'};
            app.y2AxisSecondaryComparisonTab.ItemsData = {'0'};
            app.y2AxisSecondaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @y2AxisSecondaryComparisonTabValueChanged, true);
            app.y2AxisSecondaryComparisonTab.Value = '0';
            app.DisplayonleftaxisDropDownLabel = uilabel(app.comparePanel);
            app.DisplayonleftaxisDropDownLabel.Text = 'Display on left axis';
            app.y1AxisPrimaryComparisonTab = uidropdown(app.comparePanel);
            app.y1AxisPrimaryComparisonTab.Items = {'- no selection -'};
            app.y1AxisPrimaryComparisonTab.ItemsData = {'0'};
            app.y1AxisPrimaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @y1AxisPrimaryComparisonTabValueChanged, true);
            app.y1AxisPrimaryComparisonTab.Value = '0';
            app.DisplayonleftaxisDropDown_2Label = uilabel(app.comparePanel);
            app.DisplayonleftaxisDropDown_2Label.Text = 'Display on left axis';
            app.y1AxisSecondaryComparisonTab = uidropdown(app.comparePanel);
            app.y1AxisSecondaryComparisonTab.Items = {'- no selection -'};
            app.y1AxisSecondaryComparisonTab.ItemsData = {'0'};
            app.y1AxisSecondaryComparisonTab.ValueChangedFcn = createCallbackFcn(app, @y1AxisSecondaryComparisonTabValueChanged, true);
            app.y1AxisSecondaryComparisonTab.Value = '0';
            app.rockDataComparePagePrimary = uitable(app.comparePanel);
            app.rockDataComparePagePrimary.ColumnName = {'Property'; 'Value'};
            app.rockDataComparePagePrimary.RowName = {};
            app.rockDataComparePageSecondary = uitable(app.comparePanel);
            app.rockDataComparePageSecondary.ColumnName = {'Property'; 'Value'};
            app.rockDataComparePageSecondary.RowName = {};
            app.compare2Tab = uitab(app.tabGroup);
            app.compare2Tab.Title = 'Compare2';
            app.graphPrimaryComparisonTab2 = uiaxes(app.compare2Tab);
            title(app.graphPrimaryComparisonTab2, 'Primary Experiment')
            xlabel(app.graphPrimaryComparisonTab2, 'X')
            ylabel(app.graphPrimaryComparisonTab2, 'Y')
            app.graphSecondaryComparisonTab2 = uiaxes(app.compare2Tab);
            title(app.graphSecondaryComparisonTab2, 'Secondary Experiment')
            xlabel(app.graphSecondaryComparisonTab2, 'X')
            ylabel(app.graphSecondaryComparisonTab2, 'Y')
            app.LeftAxisDropDownLabel = uilabel(app.compare2Tab);
            app.LeftAxisDropDownLabel.Text = 'Left Axis';
            app.y1AxisPrimaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.y1AxisPrimaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @y1AxisPrimaryComparisonTab2ValueChanged, true);
            app.xAxisDropDown_2Label = uilabel(app.compare2Tab);
            app.xAxisDropDown_2Label.Text = 'x-Axis';
            app.xAxisPrimaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.xAxisPrimaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @xAxisPrimaryComparisonTab2ValueChanged, true);
            app.RightAxisDropDownLabel = uilabel(app.compare2Tab);
            app.RightAxisDropDownLabel.Text = 'Right Axis';
            app.y2AxisPrimaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.y2AxisPrimaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @y2AxisPrimaryComparisonTab2ValueChanged, true);
            app.LeftAxisDropDown_2Label = uilabel(app.compare2Tab);
            app.LeftAxisDropDown_2Label.Text = 'Left Axis';
            app.y1AxisSecondaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.y1AxisSecondaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @y1AxisSecondaryComparisonTab2ValueChanged, true);
            app.xAxisDropDownLabel = uilabel(app.compare2Tab);
            app.xAxisDropDownLabel.Text = 'x-Axis';
            app.xAxisSecondaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.xAxisSecondaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @xAxisSecondaryComparisonTab2ValueChanged, true);
            app.RightAxisDropDown_2Label = uilabel(app.compare2Tab);
            app.RightAxisDropDown_2Label.Text = 'Right Axis';
            app.y2AxisSecondaryComparisonTab2 = uidropdown(app.compare2Tab);
            app.y2AxisSecondaryComparisonTab2.ValueChangedFcn = createCallbackFcn(app, @y2AxisSecondaryComparisonTab2ValueChanged, true);
            % Show the figure after all components are created
            app.triaxialExperimentResults.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.triaxialExperimentResults)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.triaxialExperimentResults)
        end
    end
end